classdef system1tx1rxView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        messageLabel                    matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Mdo3012Lamp                     matlab.ui.control.Lamp
        Mdo3012LampLabel                matlab.ui.control.Label
        SimulationSwitch                matlab.ui.control.Switch
        SimulationSwitchLabel           matlab.ui.control.Label
        kSpsLabel                       matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.EditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        BitRateEditField                matlab.ui.control.EditField
        BitRateEditFieldLabel           matlab.ui.control.Label
        NIterationEditField             matlab.ui.control.NumericEditField
        NIterationEditFieldLabel        matlab.ui.control.Label
        BitsperIterationEditField       matlab.ui.control.EditField
        BitsperIterationEditFieldLabel  matlab.ui.control.Label
        kHzLabel                        matlab.ui.control.Label
        BandwidthEditField              matlab.ui.control.EditField
        BandwidthEditFieldLabel         matlab.ui.control.Label
        kbpsLabel                       matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        UIAxesSignal                    matlab.ui.control.UIAxes
        UIAxesEyeDiagram                matlab.ui.control.UIAxes
        UIAxesProb                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        system1tx1rxController
        lastResponses
    end
    
    methods (Access = public)
        
        function setViewInputs(app, ookParams)
            app.BitRateEditField.Value = string(ookParams.bitRate/1e3); %
            app.BandwidthEditField.Value = string(ookParams.bandwidth/1e3); %kHz
            app.BitsperIterationEditField.Value = string(ookParams.recommendedBitNumber);
            app.SampleRateEditField.Value = string(ookParams.txSampleRate/1e3); %kSps
        end
        
        function updateSignalAxes(app, comResponse)
            txSamples = comResponse.txSignal.getSamples();
            txTimes =  comResponse.txSignal.getTimes();
            rxSamples = comResponse.synchSignal.getSamples();
            rxTimes =  comResponse.synchSignal.getTimes();
            
            plot(app.UIAxesSignal, txTimes(1:300), txSamples(1:300), ...
                '-', 'Color', '#0072BD', 'LineWidth', 2, ...
                'DisplayName', '\color{white} Transmitted Signal');
            hold(app.UIAxesSignal, 'on');
            plot(app.UIAxesSignal, rxTimes(1:300), rxSamples(1:300), ...
                '-', 'Color', '#EDB120', 'DisplayName', '\color{white} Received Signal');
            legend(app.UIAxesSignal, 'color', [0 0 0]);

            hold(app.UIAxesSignal, 'off');
        end
        
        function updateEyeDiagramAxes(app, comResponse)
            plot(app.UIAxesEyeDiagram, comResponse.eyeDiagramSegments', 'Color', '#EDB120');
        end
        
        function updateProbAxes(app, comResponse)
            mu1 = comResponse.qFactor.getMu1();
            mu0 = comResponse.qFactor.getMu0();
            sigma1 = comResponse.qFactor.getSigma1();
            sigma0 = comResponse.qFactor.getSigma0();
            q = comResponse.qFactor.getQ();
            
            x1 = linspace(0, max(comResponse.bit1Samples)+0.5, 50);
            x0 = linspace(min(comResponse.bit0Samples)-0.5, 0, 50);
            pdf1 = normpdf(x1, mu1, sigma1);
            pdf0 = normpdf(x0, mu0, sigma0);
            
            
            plot(app.UIAxesProb, pdf1, x1, 'b-', 'LineWidth', 2, ...
                'Color', '#0072BD', 'DisplayName', '\color{white} Gaussian 1'); % Gaussiana para bit 1
            hold(app.UIAxesProb, 'on');
            plot(app.UIAxesProb, pdf0, x0, 'r-', 'LineWidth', 2, ...
                'Color', '#A2142F', 'DisplayName', '\color{white} Gaussian 0'); % Gaussiana para bit 0
            histogram(app.UIAxesProb, comResponse.bit1Samples, ...
                'Normalization', 'pdf', 'FaceColor', '#0072BD', ...
                'FaceAlpha', 0.5, 'Orientation', 'horizontal', ...
                'DisplayName', '\color{white} Samples 1');
            histogram(app.UIAxesProb, comResponse.bit0Samples, ...
                'Normalization', 'pdf', 'FaceColor', '#A2142F', ...
                'FaceAlpha', 0.5, 'Orientation', 'horizontal', ...
                'DisplayName', '\color{white} Samples 0');
            title(app.UIAxesProb, sprintf('OOK Level probability (Q = %.2f)', q), "Color", [0.9 0.9 0.9]);
            legend(app.UIAxesProb, 'color', [0 0 0]);
            ylim(app.UIAxesProb, ylim(app.UIAxesEyeDiagram));
            hold(app.UIAxesProb, 'off');
        end
        
        function setErrorMassage(app, message)
            app.messageLabel.Text = message;
            app.messageLabel.FontColor = 'red';
        end
        
        function setInfoMassage(app, message)
            app.messageLabel.Text = message;
            app.messageLabel.FontColor = 'white';
        end
        
        function clearMessage(app)
            app.messageLabel.Text = "";
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % getting the path of this script and going two folder back (../../)
            srcMainPath = fileparts(fileparts(fileparts(mfilename('fullpath'))));
            
            %add scripts path
            addpath(genpath(srcMainPath));
            
            % creating controller obj
            app.system1tx1rxController = System1tx1rxController(app);
            
            % changin plot title color to white
            title(app.UIAxesEyeDiagram, "Eye Diagram", "Color", [0.9 0.9 0.9]);
            title(app.UIAxesProb, 'OOK Level probability', "Color", [0.9 0.9 0.9]);

            set(app.UIAxesProb, 'YAxisLocation', 'right');
            
            % setting view inputs
            app.setViewInputs(app.system1tx1rxController.getOokParameters())
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.clearMessage();
            nInter = app.NIterationEditField.Value;
            app.lastResponses = [];
            for i = 1:nInter
                response = app.system1tx1rxController.communicate();
                app.updateSignalAxes(response);
                app.updateEyeDiagramAxes(response);
                app.updateProbAxes(response);
                app.setInfoMassage(sprintf("Iteration %d | numBitErrors= %d | BER = %f", i, response.numBitErrors, response.ber));
                app.lastResponses = [app.lastResponses response];
            end
        end

        % Value changed function: SimulationSwitch
        function SimulationSwitchValueChanged(app, event)
            value = app.SimulationSwitch.Value;
            if(value == "Off")
                app.system1tx1rxController.switchToMdo();
            else
                app.system1tx1rxController.switchToSimulation();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if(~isempty(app.lastResponses))
                path = fileparts(fileparts(fileparts(fileparts(fileparts(mfilename('fullpath'))))));
                folderName = datestr(datetime('now'), 'yyyy_mm_dd_HH_MM_SS');
                dataFolderPath = fullfile(path, "data/"+folderName);
                responses = app.lastResponses;
                save(dataFolderPath, 'responses');
                app.setInfoMassage("The data was saved at " + dataFolderPath)
            else
                app.setErrorMassage("Run a transmission first")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.system1tx1rxController.disconnectAnalizer()
            delete(app)
            
        end

        % Value changed function: NIterationEditField
        function NIterationEditFieldValueChanged(app, event)
            value = app.NIterationEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesProb = uiaxes(app.UIFigure);
            xlabel(app.UIAxesProb, 'Prob. Density')
            ylabel(app.UIAxesProb, 'Amplitude')
            zlabel(app.UIAxesProb, 'Z')
            app.UIAxesEyeDiagram = uiaxes(app.UIFigure);
            xlabel(app.UIAxesEyeDiagram, 'Time')
            ylabel(app.UIAxesEyeDiagram, 'Amplitude')
            zlabel(app.UIAxesEyeDiagram, 'Z')
            app.UIAxesSignal = uiaxes(app.UIFigure);
            xlabel(app.UIAxesSignal, 'Time')
            ylabel(app.UIAxesSignal, 'Amplitude')
            zlabel(app.UIAxesSignal, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.kbpsLabel = uilabel(app.UIFigure);
            app.kbpsLabel.Text = 'kbps';
            app.BandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.UIFigure, 'text');
            app.BandwidthEditField.Editable = 'off';
            app.BandwidthEditField.Tooltip = {'Bw = Rb'};
            app.kHzLabel = uilabel(app.UIFigure);
            app.kHzLabel.Tooltip = {''};
            app.kHzLabel.Text = 'kHz';
            app.BitsperIterationEditFieldLabel = uilabel(app.UIFigure);
            app.BitsperIterationEditFieldLabel.Text = 'Bits per Iteration';
            app.BitsperIterationEditField = uieditfield(app.UIFigure, 'text');
            app.BitsperIterationEditField.Editable = 'off';
            app.BitsperIterationEditField.Tooltip = {'Considerando o sinal ocupando 50% do buffer de 10k points do mdo3012'};
            app.NIterationEditFieldLabel = uilabel(app.UIFigure);
            app.NIterationEditFieldLabel.Text = 'N. Iteration';
            app.NIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NIterationEditField.ValueChangedFcn = createCallbackFcn(app, @NIterationEditFieldValueChanged, true);
            app.NIterationEditField.Value = 1;
            app.BitRateEditFieldLabel = uilabel(app.UIFigure);
            app.BitRateEditFieldLabel.Text = 'Bit Rate';
            app.BitRateEditField = uieditfield(app.UIFigure, 'text');
            app.BitRateEditField.Editable = 'off';
            app.SampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.UIFigure, 'text');
            app.SampleRateEditField.Editable = 'off';
            app.kSpsLabel = uilabel(app.UIFigure);
            app.kSpsLabel.Text = 'kSps';
            app.SimulationSwitchLabel = uilabel(app.UIFigure);
            app.SimulationSwitchLabel.Text = 'Simulation';
            app.SimulationSwitch = uiswitch(app.UIFigure, 'slider');
            app.SimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchValueChanged, true);
            app.Mdo3012LampLabel = uilabel(app.UIFigure);
            app.Mdo3012LampLabel.Text = 'Mdo3012';
            app.Mdo3012Lamp = uilamp(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.messageLabel = uilabel(app.UIFigure);
            app.messageLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = system1tx1rxView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end